classdef Final_app_sunday_12pm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        SimplifiedCurveFittingLabel    matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        ImageReadIntensitySliderLabel  matlab.ui.control.Label
        ImageReadIntensitySlider       matlab.ui.control.Slider
        Im2GrayImageButton             matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        ShowOutputButton               matlab.ui.control.Button
        EditField_2                    matlab.ui.control.NumericEditField
        OriginalCurveCheckBox          matlab.ui.control.CheckBox
        SmoothCurveCheckBox            matlab.ui.control.CheckBox
        ThresholdGapSliderLabel        matlab.ui.control.Label
        ThresholdGapSlider             matlab.ui.control.Slider
        UITable                        matlab.ui.control.Table
        AverageRMSErrorEditFieldLabel  matlab.ui.control.Label
        AverageRMSErrorEditField       matlab.ui.control.NumericEditField
        CloseButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        img % Description
        img2 % Description
        imageread_intesity % Description
        threshold_gap % Description
        threshold_gap_per=4% Description
        smoothx % Description
        smoothy % Description
        realx % Description
        realy % Description
    end

    methods (Access = private)


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [x2,y2] = smoothsc(app,A)

            % x2 is the 1*n vector of x-coordinates after smoothing.
            % y2 is the 1*n vector of y-coordinates after smoothing.

            [row2,col] = size(A);
            k = 1;
            m=zeros( row2*col,2);
            % Find points from the image.
            % '0' corrsponds Black points.
            for i = 1:row2
                for j = 1:col
                    if A(i,j) == 0
                        m(k,1) = j; % x axis values on col 1
                        m(k,2) = i; % y axis values on 2
                        k = k+1;
                    end
                end
            end
            d=m(1:k-1, :);
            % Ordinate of the lowest point has assumesd to be 1.
            % '1' has added to avoid log(0) error.
            d(:,2) = max( d(:,2) )  +  1-  d(:,2); %taking the y axis value of black points

            % Sort the points.
            d = sortrows(d);
            x = d(:,1);
            y = d(:,2);

%             Plot original points.
%                         scatter(x,y);
%                         hold on;
            app.realx=x;
            app.realy=y;

            k = length(x);
            p=1;
            x1(p) = x(1);
            y1(p) = y(1);

            sum =y1(p);
            smoothCount = 1;
            % Smooth vertical lines.
            i = 2;

            while i <= k
            	while (x(i) == x1(p))
            		sum = sum + y(i);
                    smoothCount = smoothCount+1;
            		i = i + 1;
                    if(i > k)
            			break; %for safety to let not i go beyond index var limit
            		end
                end
            	if(smoothCount > 1)
            		y1(p) = (sum/smoothCount); %round
            	end
            	if(i<k)
            		p = p + 1;
            		x1(p) = x(i);
            		y1(p) = y(i);
            		sum = y1(p);
            		smoothCount = 1;
            	end
            	i = i+1;
            end
            k = p;
            p=1;


            x2(p) = x1(1);
            y2(p) = y1(1);


            sum = x2(p);
            smoothCount = 1;
            % Smooth vertical lines.
            i = 2;

            while i <= k
            	while (y1(i) == y2(p))
            		sum = sum + x1(i);
                    smoothCount = smoothCount+1;
            		i = i + 1;
                    if(i > k)
            			break;
            		end
                end
            	if(smoothCount > 1)
            		x2(p) = (sum/smoothCount); %round
            	end
            	if(i<k)
            		p = p + 1;
            		x2(p) = x1(i);
            		y2(p) = y1(i);
            		sum = x2(p);
            		smoothCount = 1;
            	end
            	i = i+1;
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [peakx,peaky,px] = findpeaks(app,x2,y2)
            % peakx is the vector of x_value of the peaks and crests.
            % peaky is the vector of y_value of the peaks and crests.
            % px is the vector of position of the peaks and crests.
            k = length(x2);
            j = 1;

            % Set first image point to be a peak.
            peakx(1) = x2(1);
            peaky(1) = y2(1);
            px(1) = 1;
            for i = 1:k-2

                % Search peak.
                % A point is a peak if f(x)>f(x+h) && f(x)>f(x-h).
                if y2(i+1)>y2(i) && y2(i+1)>y2(i+2)
                    j = j+1;
                    peakx(j) = x2(i+1);
                    peaky(j) = y2(i+1);
                    px(j) = i+1;
                end

                % Search crest.
                % A point is a crest if f(x)<f(x+h) && f(x)<f(x-h).
                if y2(i+1)<y2(i) && y2(i+1)<y2(i+2)
                    j = j+1;
                    peakx(j) = x2(i+1);
                    px(j) = i+1;
                    peaky(j) = y2(i+1);
                end
            end

            % Set last image point to be a peak.
            peakx(j+1) = x2(k);
            peaky(j+1) = y2(k);
            px(j+1) = k;
        end

        function  [r,ym,error_for_rms]= bestfitcurve(app,x,y)
            % r is the vector of coefficients of the best fitted equation.
            % ym is the vector of fitted ordinates.

            % Linear fit, y = mx+c.
            r1 = polyfit(x,y,1);
            ym1 = polyval(r1,x);
            r = r1;
            ym = ym1;
            e1 = sum(abs(y-ym1));
            e = e1;
            error_for_rms = abs(y-ym1);


            % Second degree polynomial fit, y = ax^2+bx+c.
            r2 = polyfit(x,y,2);
            ym2 = polyval(r2,x);
            e2 = sum(abs(y-ym2));
            if e2<e1
                clear r;
                clear ym;
                clear error_for_rms;
                r = r2;
                ym = ym2;
                e = e2;
                error_for_rms = abs(y-ym2);
            end

            % Third degree polynomial fit, y = ax^3+bx^2+cx+d.
            r2 = polyfit(x,y,3);
            ym2 = polyval(r2,x);
            e2 = sum(abs(y-ym2));
            if e2<e
                clear r;
                clear ym;
                clear error_for_rms;
                r = r2;
                ym = ym2;
                e = e2;
                error_for_rms = abs(y-ym2);
            end


            % In rational, exponential, circular fit the coefficient
            % vector includes some extra zeros so that from the length
            % of the coefficient vector the real equation can be identified.

            % Rational function fit, y = (x+A)/(Bx+C).
            [A,B,C] = ratreg(x,y);
            ym2 = (x+A)./(B.*x+C);
            r2 = [0 0 A B C];  % show ans use case for n == 5
            e2 = sum(abs(y-ym2));
            if e2<e
                clear r;
                clear ym;
                clear error_for_rms;
                r = r2;
                ym = ym2;
                e = e2;
                error_for_rms = abs(y-ym2);
            end

            % Exponential fit. y = C*exp(Ax).
            [C,A] = expreg(x,y);
            ym2 = C.*exp(A.*x);
            r2 = [0 0 0 0 C A]; % for the show ans n == 6
            e2 = sum(abs(y-ym2));
            if e2<e
                clear r;
                clear ym;
                clear error_for_rms;
                r = r2;
                ym = ym2;
                e = e2;
                error_for_rms = abs(y-ym2);
            end

            % Circular fit, x^2+y^2+2gx+2fy+c = 0.
            [g,f,c] = circulareg(x,y);
            ym21 = -f+abs(sqrt(g^2+f^2-c-(x+g).^2));
            ym22 = -f-abs(sqrt(g^2+f^2-c-(x+g).^2));
            r2 = [0 0 0 0 g f c];  %for showans n==7
            e21 = sum(abs(y-ym21));
            e22 = sum(abs(y-ym22));
            if e22>e21
                e2 = e21;
            else
                e2 = e22;
            end
            if e2<e
                clear r;
                clear ym;
                clear error_for_rms;
                r = r2;
                ym = ym2;
                e = e2;
                error_for_rms = abs(y-ym2);
            end

            % Plot the curve for which sum of errors is minimum.
            %             plot(x,ym,'r');
            plot(app.UIAxes3,x,ym,'r');

        end
    end
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file,path]=uigetfile('*.*');
            filepath=strcat(path,file);
            app.img=imread(filepath);
            imshow(app.img,"Parent",app.UIAxes);
        end

        % Callback function: Im2GrayImageButton, 
        % ImageReadIntensitySlider
        function DisplayImageAfterIntensityReaderButtonPushed(app, event)
            app.imageread_intesity=app.ImageReadIntensitySlider.Value;

            app.img2=app.img;
            app.img2 = im2gray(app.img2);
            app.img2 = app.img2> 255*app.imageread_intesity*0.01;
            imshow(app.img2,"Parent",app.UIAxes2)
        end

        % Value changing function: ImageReadIntensitySlider
        function ImageReadIntensitySliderValueChanging(app, event)
            app.imageread_intesity = event.Value;
            app.EditField.Value=app.imageread_intesity;
        end

        % Callback function: ShowOutputButton, ThresholdGapSlider
        function ShowOutputButtonPushed(app, event)
            format short;
            warning off;
            [row , col] = size (app.img2);
            cla(app.UIAxes3) ;
            [app.smoothx,app.smoothy] = smoothsc(app,app.img2);
            clear app.UIAxes3;

            % plot the smoothened curve
            % %  plot(app.UIAxes3,x,y, 'LineWidth', 1.5, 'color', 'k');


            % Find Peak and Crest points.
            [peakx,peaky,px] = findpeaks(app,app.smoothx,app.smoothy);


            % Number of equations is Number of peaks minus 1.
            q = length(px);
            s = 1;
            i = 1;

            t=readtable("data_c.xlsx","Sheet",1);
            app.UITable.Data=t;
            %          p.threshold_gap=
            %             app.AverageRMSErrorEditField.Value=thth;


            while i <= q-1
                if ((((px(i+s)-px(i))) >(row*app.threshold_gap_per)*0.01)|| (i+s == q))
                    m = px(i);
                    n = px(i+s);
                    % Segment the curve for fittng.
                    [r,ym,error_for_rms] = bestfitcurve(app,app.smoothx(m:n),app.smoothy(m:n));
                    if i==1
                        error_col = error_for_rms;
                    end
                    if i>1
                        error_col = [error_col error_for_rms];
                    end
                    % Display results.
                    eq=showans(r,ym,peakx(i),peakx(i+s)); %fitted graph display
%                     
%                     lll=simscapeEquation(eq);
                    
                    nr={peakx(i) peakx(i+s) eq};
                    app.UITable.Data=[t;nr];
                    t=app.UITable.Data;
                    i = i+s;

                    plot(app.UIAxes3,peakx(i),peaky(i),'ko','LineWidth',2.0); %peak crest display
                    s = 1; %setting the s again to the base to compare the current peak with the immediate next one
                else
                    s = s+1;
                    continue
                end
            end
            rmserrorfinal = rms(error_col); %rms error display
            app.AverageRMSErrorEditField.Value=rmserrorfinal;
        end

        % Value changing function: ThresholdGapSlider
        function ThresholdGapSliderValueChanging(app, event)
            app.threshold_gap_per= event.Value;
            app.EditField_2.Value=app.threshold_gap_per;


        end

        % Value changed function: SmoothCurveCheckBox
        function SmoothCurveCheckBoxValueChanged(app, event)
            value = app.SmoothCurveCheckBox.Value;
            if(value)
                plot(app.UIAxes3,app.smoothx,app.smoothy, 'LineWidth', 1.5, 'color', 'k');
            end
        end

        % Value changed function: OriginalCurveCheckBox
        function OriginalCurveCheckBoxValueChanged(app, event)
            value=app.OriginalCurveCheckBox.Value;
            if value
                plot(app.UIAxes3,app.realx,app.realy);
            end
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            YN=uiconfirm(app.UIFigure,'Do you want to close the app?','Close Request');
            if strcmpi(YN,'OK')
                  delete(app)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            zlabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SimplifiedCurveFittingLabel = uilabel(app.UIFigure);
            app.SimplifiedCurveFittingLabel.Text = 'Simplified Curve Fitting';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ImageReadIntensitySliderLabel = uilabel(app.UIFigure);
            app.ImageReadIntensitySliderLabel.Text = 'Image Read Intensity';
            app.ImageReadIntensitySlider = uislider(app.UIFigure);
            app.ImageReadIntensitySlider.Limits = [1 100];
            app.ImageReadIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @DisplayImageAfterIntensityReaderButtonPushed, true);
            app.ImageReadIntensitySlider.ValueChangingFcn = createCallbackFcn(app, @ImageReadIntensitySliderValueChanging, true);
            app.ImageReadIntensitySlider.Value = 1;
            app.Im2GrayImageButton = uibutton(app.UIFigure, 'push');
            app.Im2GrayImageButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayImageAfterIntensityReaderButtonPushed, true);
            app.Im2GrayImageButton.Text = 'Im2Gray Image';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowOutputButton = uibutton(app.UIFigure, 'push');
            app.ShowOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOutputButtonPushed, true);
            app.ShowOutputButton.Text = 'Show Output';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.OriginalCurveCheckBox = uicheckbox(app.UIFigure);
            app.OriginalCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @OriginalCurveCheckBoxValueChanged, true);
            app.OriginalCurveCheckBox.Text = 'Original Curve';
            app.SmoothCurveCheckBox = uicheckbox(app.UIFigure);
            app.SmoothCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothCurveCheckBoxValueChanged, true);
            app.SmoothCurveCheckBox.Text = 'Smooth Curve';
            app.ThresholdGapSliderLabel = uilabel(app.UIFigure);
            app.ThresholdGapSliderLabel.Text = 'Threshold Gap Slider';
            app.ThresholdGapSlider = uislider(app.UIFigure);
            app.ThresholdGapSlider.Limits = [0.001 100];
            app.ThresholdGapSlider.ValueChangedFcn = createCallbackFcn(app, @ShowOutputButtonPushed, true);
            app.ThresholdGapSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdGapSliderValueChanging, true);
            app.ThresholdGapSlider.Value = 0.001;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lower Limit'; 'Upper Limit'; 'Equation'};
            app.UITable.RowName = {};
            app.AverageRMSErrorEditFieldLabel = uilabel(app.UIFigure);
            app.AverageRMSErrorEditFieldLabel.Text = 'Average RMS Error';
            app.AverageRMSErrorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_app_sunday_12pm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end